classdef AnimatorControls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TimeSlider             matlab.ui.control.Slider
        LabelNumericEditField  matlab.ui.control.Label
        TimeField              matlab.ui.control.NumericEditField
        LabelDropDown          matlab.ui.control.Label
        POVDropDown            matlab.ui.control.DropDown
        PlayButton             matlab.ui.control.StateButton
        LoopButton             matlab.ui.control.StateButton
        BackButton             matlab.ui.control.Button
        Button                 matlab.ui.control.Button
    end


    properties (Access = public)
        anim = 0;
        TimeStepListener = 0;
        Playlistener = 0;
        Closelistener = 0;
    end
    
    methods
        function set.anim(app, anim)
            
            app.TimeStepListener = addlistener(anim, 'newTimeStep', @(s, e) app.ListenToNewTimeStep(s, e));
            app.Playlistener = addlistener(anim, 'playStateChanged', @(s, e) app.ListenToPlayStateChanged(s, e));
            %app.Closelistener = addlistener(anim, 'animDeleting', @(s, e) app.ListenToClosing(s, e));
            
            app.anim = anim;
            app.TimeSlider.Limits = [anim.startTime, anim.endTime];
            app.TimeSlider.MajorTicks = [anim.startTime, anim.endTime];
            
            app.PlayButton.Value = anim.isPlaying;
            app.LoopButton.Value = anim.isLooping;
            
            app.POVDropDown.Value = MyAnimator.AnimatorControls.POVToText(anim.pov);
        end
        
        function ListenToNewTimeStep(app, src, eventData)
            app.TimeSlider.Value = eventData.t;
            app.TimeField.Value = eventData.t;
        end
        
        function ListenToPlayStateChanged(app, src, eventData)
            app.PlayButton.Value = app.anim.isPlaying;
        end
        
        function ListenToClosing(app, src, eventData)
            disp('WOW');
            delete(app);
        end
    end

    methods (Access = public, Static)
        function pov = TextToPOV(text)
            switch(text)
                case 'North'
                    pov = MyAnimator.AnimatorPointOfView.North;
                case 'South'
                    pov = MyAnimator.AnimatorPointOfView.South;
                case 'East'
                    pov = MyAnimator.AnimatorPointOfView.East;
                case 'West'
                    pov = MyAnimator.AnimatorPointOfView.West;
                case 'Front'
                    pov = MyAnimator.AnimatorPointOfView.Front;
                case 'Back'
                    pov = MyAnimator.AnimatorPointOfView.Back;
                case 'Left'
                    pov = MyAnimator.AnimatorPointOfView.Left;
                case 'Right'
                    pov = MyAnimator.AnimatorPointOfView.Right;
                case 'Top Front Left'
                    pov = MyAnimator.AnimatorPointOfView.TopFrontLeft;
                case 'Top South East'
                    pov = MyAnimator.AnimatorPointOfView.TopSouthEast;
                otherwise
                    pov = MyAnimator.AnimatorPointOfView.Free;
            end
        end
        
        function text = POVToText(pov)
            switch(pov)
                case MyAnimator.AnimatorPointOfView.North
                    text = 'North';
                case MyAnimator.AnimatorPointOfView.South
                    text = 'South';
                case MyAnimator.AnimatorPointOfView.East
                    text = 'East';
                case MyAnimator.AnimatorPointOfView.West
                    text = 'West';
                case MyAnimator.AnimatorPointOfView.Front
                    text = 'Front';
                case MyAnimator.AnimatorPointOfView.Back
                    text = 'Back';
                case MyAnimator.AnimatorPointOfView.Left
                    text = 'Left';
                case MyAnimator.AnimatorPointOfView.Right
                    text = 'Right';
                case MyAnimator.AnimatorPointOfView.TopFrontLeft
                    text = 'Top Front Left';
                case MyAnimator.AnimatorPointOfView.TopSouthEast
                    text = 'Top South East';
                case MyAnimator.AnimatorPointOfView.Free
                    text = 'Free';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: TimeSlider
        function TimeSliderChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.TimeSlider.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Value changed function: TimeField
        function TimeFieldChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.TimeField.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Value changed function: POVDropDown
        function POVChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.POVDropDown.Value;
            
            app.anim.pov = MyAnimator.AnimatorControls.TextToPOV(value);
            app.anim.Animate(true);
        end

        % Value changed function: PlayButton
        function PlayChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.PlayButton.Value;
            
            app.anim.isPlaying = value;
        end

        % Value changed function: LoopButton
        function LoopChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.LoopButton.Value;
            
            app.anim.isLooping = value;
        end

        % Value changing function: TimeSlider
        function TimeSliderChanging(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = event.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Button pushed function: Button
        function SkipForward(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            app.anim.currentTime = app.anim.currentTime + 0.05;
            app.anim.Animate(true);
        end

        % Button pushed function: BackButton
        function SkipBackward(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            app.anim.currentTime = app.anim.currentTime - 0.05;
            app.anim.Animate(true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.MajorTicks = [0 100];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Time';
            app.TimeField = uieditfield(app.UIFigure, 'numeric');
            app.TimeField.ValueDisplayFormat = '%04.03f';
            app.TimeField.ValueChangedFcn = createCallbackFcn(app, @TimeFieldChanged, true);
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Drop Down';
            app.POVDropDown = uidropdown(app.UIFigure);
            app.POVDropDown.Items = {'North', 'South', 'East', 'West', 'Front', 'Back', 'Left', 'Right', 'Top South East', 'Top Front Left', 'Free'};
            app.POVDropDown.Editable = 'on';
            app.POVDropDown.ValueChangedFcn = createCallbackFcn(app, @POVChanged, true);
            app.POVDropDown.Value = 'Top South East';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayChanged, true);
            app.PlayButton.Text = 'Play';
            app.LoopButton = uibutton(app.UIFigure, 'state');
            app.LoopButton.ValueChangedFcn = createCallbackFcn(app, @LoopChanged, true);
            app.LoopButton.Text = 'Loop';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @SkipBackward, true);
            app.BackButton.Text = '<< Backward';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SkipForward, true);
            app.Button.Text = 'Forward >>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimatorControls

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end